classdef segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnatoScopeLabel                 matlab.ui.control.Label
        SegmentatedimagedetailsPanel    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Label6                          matlab.ui.control.Label
        Label5                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3_2                        matlab.ui.control.Label
        SpaFilteringLabel               matlab.ui.control.Label
        ConFilteringLabel               matlab.ui.control.Label
        ThresMethodLabel                matlab.ui.control.Label
        ResolutionLabel                 matlab.ui.control.Label
        SegmentatedimagePanel           matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        ThumbnailPanel                  matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ConfigurationPanel              matlab.ui.container.Panel
        CreditsAknowledgementsButton    matlab.ui.control.Button
        Label7                          matlab.ui.control.Label
        InstructionsButton              matlab.ui.control.Button
        ImagePanel                      matlab.ui.container.Panel
        ChoosesampleimageDropDown       matlab.ui.control.DropDown
        ChoosesampleimageDropDownLabel  matlab.ui.control.Label
        orLabel                         matlab.ui.control.Label
        UploadfileButton                matlab.ui.control.Button
        ThresholdingmethodButtonGroup   matlab.ui.container.ButtonGroup
        Label3                          matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        FixedThresholdButton            matlab.ui.control.ToggleButton
        KapurButton                     matlab.ui.control.ToggleButton
        OtsuButton                      matlab.ui.control.ToggleButton
        ContrastfilteringButtonGroup    matlab.ui.container.ButtonGroup
        adaptiveequalizationButton      matlab.ui.control.ToggleButton
        greyscalehistogramButton        matlab.ui.control.ToggleButton
        saturationadjustmentButton      matlab.ui.control.ToggleButton
        SpatialfilteringButtonGroup     matlab.ui.container.ButtonGroup
        MotionButton_2                  matlab.ui.control.ToggleButton
        PrewittButton_2                 matlab.ui.control.ToggleButton
        Label2                          matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        DiskButton                      matlab.ui.control.ToggleButton
        LogaritmicButton                matlab.ui.control.ToggleButton
        LaplacianButton                 matlab.ui.control.ToggleButton
        GaussianButton                  matlab.ui.control.ToggleButton
        MediaButton                     matlab.ui.control.ToggleButton
        SaltpepperButton                matlab.ui.control.ToggleButton
        SegmentateButton                matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        imagn1 % input image
        method % thresholding method
        metval % Value for method
        contfilt % contrast filtering method
        spafilt % spacial filtering method
        spaval % spacial filtering value
    end
    
    methods (Access = private)
            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
        app.method = "Otsu";
        app.metval = 0;
        app.contfilt = "saturation adjustment";
        app.spafilt = "Salt & pepper";
        app.spaval = 0;

        end

        % Button pushed function: UploadfileButton
        function UploadfileButtonPushed(app, event)
            % when upload file is pushed:

            % Get the file from the matlab drive folder, save in orig1
            [filename, pathname] = uigetfile({'*.jpg'},"Open file");
            fullpathname =strcat(pathname, filename);
            orig1 = imread(fullpathname);

            % Determine if image is B&W or RGB
            sz = size (orig1);
            dim = numel(sz);
            
            % If image is RGB, Make it B&W
            if dim == 3
                app.imagn1=rgb2gray(orig1);
            else app.imagn1=orig1;
            end

            % Show image thumbnail in the corner
            imshow(app.imagn1,'parent',app.UIAxes);
            app.SegmentateButton.Visible = "on";
        end

        % Value changed function: ChoosesampleimageDropDown
        function ChoosesampleimageDropDownValueChanged(app, event)
            value = app.ChoosesampleimageDropDown.Value;
            % When a sample image is selected:
         
            % read sample image in orig1
            switch value
                case "1" 
                    orig1 = imread("/MATLAB Drive/AA PROCESAMIENTO/app resources/sample1.jpg");
                    none = 0;
                case "2"
                    orig1 = imread("/MATLAB Drive/AA PROCESAMIENTO/app resources/sample2.jpg");
                    none = 0;
                case "3"
                    orig1 = imread("/MATLAB Drive/AA PROCESAMIENTO/app resources/sample3.jpg");
                    none = 0;
                case "none"
                    none = 1;  
            end

            % if none clear thumbnail, else convert image to B&W and display
            if none == 1
                cla(app.UIAxes, 'reset')
                app.SegmentateButton.Visible="off";
            else 
                sz = size (orig1);
                dim = numel(sz);
                if dim == 3
                    app.imagn1=rgb2gray(orig1);
                else app.imagn1=orig1;
                end
            imshow(app.imagn1,'parent',app.UIAxes);
            app.SegmentateButton.Visible="on";
            end
        end

        % Callback function: Slider, ThresholdingmethodButtonGroup
        function ThresholdingmethodButtonGroupSelectionChanged(app, event)
            % find out selection and save it:
            selectedButton = app.ThresholdingmethodButtonGroup.SelectedObject;
            selection = selectedButton.Text;
            app.method = selection;

            % save configuration for segmentation (and magic slider)
            switch selection
                case "Otsu"
                  app.Slider.Visible="off";
                  app.Label3.Visible="off";
                  sld=0;
                case "Kapur"
                  app.Slider.Visible="off";
                  app.Label3.Visible="off";
                  sld=0;
                case "Fixed Threshold"
                  app.Slider.Visible="on";
                  app.Label3.Visible="on";
                  sld=app.Slider.Value;
            end
            
            %slider label
            app.metval=round(sld,2);
            app.Label3.Text = num2str(app.metval);
            
        end

        % Selection changed function: ContrastfilteringButtonGroup
        function ContrastfilteringButtonGroupSelectionChanged(app, event)
            % find out selection and save it:
            selectedButton = app.ContrastfilteringButtonGroup.SelectedObject;
            selection = selectedButton.Text;
            app.contfilt = selection;
            
        end

        % Callback function: Slider_2, SpatialfilteringButtonGroup
        function SpatialfilteringButtonGroupSelectionChanged(app, event)
            % find out selection and save it:
            selectedButton = app.SpatialfilteringButtonGroup.SelectedObject;
            selection =selectedButton.Text;
            app.spafilt = selection;

            % save configuration for segmentation (and magic slider)
            switch selection
                case "Salt & pepper"
                  app.Slider_2.Visible="off";
                  app.Label2.Visible="off";
                  sld=0;
                case "Media"
                  app.Slider_2.Limits = [5,100];
                  app.Slider_2.Visible="on";
                  app.Label2.Visible="on";
                  sld=app.Slider_2.Value;
                  sld=round(sld);
                case "Gaussian"
                  app.Slider_2.Limits = [0,1];
                  app.Slider_2.Visible="on";
                  app.Label2.Visible="on";
                  sld=app.Slider_2.Value;
                case "Prewitt"
                  app.Slider_2.Visible="off";
                  app.Label2.Visible="off";
                  sld=0;
                case "Laplacian"
                  app.Slider_2.Visible="off";
                  app.Label2.Visible="off";
                  sld=0;
                case "Logaritmic"
                  app.Slider_2.Limits = [0,1];
                  app.Slider_2.Visible="on";
                  app.Label2.Visible="on";
                  sld=app.Slider_2.Value;
                case "Disk"
                  app.Slider_2.Visible="off";
                  app.Label2.Visible="off";
                  sld=0;
                case "Motion"
                  app.Slider_2.Limits = [1,360];
                  app.Slider_2.Visible="on";
                  app.Label2.Visible="on";
                  sld=app.Slider_2.Value;
            end
            
            %slider label
            app.spaval=round(sld,2);
            app.Label2.Text = num2str(app.spaval);
            
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
            app.Label7.Text = sprintf("1. Upload an image or choose one of our sample images.\n2. Choose a Thresholding Method " + ...
                "(Use the slider to adjust the reference value if needed).\n3. Choose a contrast filtering method.\n4. Choose a " + ...
                "spatial filtering method (Use the slider to adjust the reference value if needed).\n5. Segmentate!");
            app.Label7.Visible="on";
            
        end

        % Button pushed function: CreditsAknowledgementsButton
        function CreditsAknowledgementsButtonPushed(app, event)
            app.Label7.Text = sprintf("Made by:\nKaren Coronado\nLaura Rojas\nEstrellita Cely\n\nSpecial thanks to:\nECCI University");
            app.Label7.Visible="on";
        end

        % Button pushed function: SegmentateButton
        function SegmentateButtonPushed(app, event)
            
        % contrast filtering 
        switch app.contfilt
            case "adaptive equalization"
              imagn1_cont = adapthisteq(app.imagn1);
            case "grey-scale histogram"
              imagn1_cont = histeq(app.imagn1); 
            case "saturation adjustment"
              imagn1_cont = imadjust(app.imagn1);
        end

        % spatial filtering
        switch app.spafilt
                case "Salt & pepper"
                  imagn1_pre = medfilt2(imagn1_cont);
                case "Media"
                  mean_filter = fspecial('average', app.spaval);
                  imagn1_pre = imfilter(imagn1_cont, mean_filter);
                case "Gaussian"
                  gaussian_filter = fspecial('gaussian', 5, app.spaval);
                  imagn1_pre = imfilter(imagn1_cont, gaussian_filter, 'replicate');
                case "Prewitt"
                   prewitt_filter = fspecial('prewitt');
                   imagn1_pre = imfilter(imagn1_cont, prewitt_filter, 'replicate');
                case "Laplacian"
                   laplacian_filter = fspecial('laplacian');
                   imagn1_pre = imfilter(imagn1_cont,laplacian_filter, 'replicate');
                case "Logaritmic"
                   log_filter = fspecial('log', 5, app.spaval);
                   imagn1_pre = imfilter(imagn1_cont, log_filter, "replicate");
                case "Disk"
                   disk_filter = fspecial('disk', 5);
                   imagn1_pre = imfilter(imagn1_cont, disk_filter,"replicate");
                case "Motion"
                   motion_filter = fspecial('motion', 20, app.spaval);
                   imagn1_pre = imfilter(imagn1_cont, motion_filter,"replicate");
        end

        %threshold method
        switch app.method
            case "Otsu"
                n=imhist(imagn1_pre); 
                N=sum(n); 
                maxo=0; 
                for i=1:256
                    P(i)=n(i)/N; 
                end
                for T=2:255
                    w0=sum(P(1:T)); 
                    w1=sum(P(T+1:256)); 
                    u0=dot([0:T-1],P(1:T))/w0; 
                    u1=dot([T:255],P(T+1:256))/w1; 
                    sigma=w0*w1*((u1-u0)^2); 
                    if sigma>maxo
                        maxo=sigma;
                        threshold=T-1; 
                    end
                end
                bw=im2bw(imagn1_pre,threshold/255); 
                imshow(bw,'parent',app.UIAxes2);
            case "Kapur"
                imag = imagn1_pre(:, :, 1); 
                counts = imhist(imag); 
                GradeI = 256;
                prob = counts ./ sum(counts); 
                psai = zeros(GradeI, 1);    
                prob_t = 0;
                entropy_t = 0;              
                ind = find(prob);
                entropy_L = sum( prob(ind) .* log(prob(ind)) ) * (-1); 
                for i = 0 : GradeI-1
                    prob_t = prob_t + prob(i+1);
                    if prob(i+1) > 0 && prob_t < 1
                        entropy_t = entropy_t - prob(i+1) * log(prob(i+1));
                        psai(i+1) = log(prob_t * (1-prob_t)) + entropy_t / prob_t + ...
                        (entropy_L-entropy_t) / (1-prob_t);
                    elseif (prob(i+1) == 0 && i > 0) 
                        psai(i+1) = psai(i);
                    end
                end
                [maxPsai, ind] = max(psai);
                th = (ind - 1) / (GradeI -1);
                imagBW = im2bw(imagn1_pre, th);
                imshow(imagBW,'parent',app.UIAxes2);
            case "Fixed Threshold"
                fixed_threshold = app.metval; 
                image1_fixed = imbinarize(imagn1_pre, fixed_threshold);
                imshow(image1_fixed,'parent',app.UIAxes2);
        end

        % deets sheet
        sz= size(app.imagn1);
        res=num2str(sz);
        app.Label3_2.Text = res;
        app.Label3_2.Visible="on";
        app.Label4.Text = app.method;
        app.Label4.Visible="on";
        app.Label5.Text = app.contfilt;
        app.Label5.Visible="on";
        app.Label6.Text = app.spafilt;
        app.Label6.Visible="on";
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.SegmentateButton = uibutton(app.ConfigurationPanel, 'push');
            app.SegmentateButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentateButtonPushed, true);
            app.SegmentateButton.Icon = fullfile(pathToMLAPP, 'AA PROCESAMIENTO', 'app resources', 'play123.png');
            app.SegmentateButton.Visible = 'off';
            app.SegmentateButton.Text = 'Segmentate!';
            app.SpatialfilteringButtonGroup = uibuttongroup(app.ConfigurationPanel);
            app.SpatialfilteringButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpatialfilteringButtonGroupSelectionChanged, true);
            app.SpatialfilteringButtonGroup.Title = 'Spatial filtering';
            app.SaltpepperButton = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.SaltpepperButton.Text = 'Salt & pepper';
            app.SaltpepperButton.Value = true;
            app.MediaButton = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.MediaButton.Text = 'Media';
            app.GaussianButton = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.LaplacianButton = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.LaplacianButton.Text = 'Laplacian';
            app.LogaritmicButton = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.LogaritmicButton.Text = 'Logaritmic';
            app.DiskButton = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.DiskButton.Text = 'Disk';
            app.Label_2 = uilabel(app.SpatialfilteringButtonGroup);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.Slider_2 = uislider(app.SpatialfilteringButtonGroup);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SpatialfilteringButtonGroupSelectionChanged, true);
            app.Slider_2.Visible = 'off';
            app.Label2 = uilabel(app.SpatialfilteringButtonGroup);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.PrewittButton_2 = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.PrewittButton_2.Text = 'Prewitt';
            app.MotionButton_2 = uitogglebutton(app.SpatialfilteringButtonGroup);
            app.MotionButton_2.Text = 'Motion';
            app.ContrastfilteringButtonGroup = uibuttongroup(app.ConfigurationPanel);
            app.ContrastfilteringButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContrastfilteringButtonGroupSelectionChanged, true);
            app.ContrastfilteringButtonGroup.Title = 'Contrast filtering';
            app.saturationadjustmentButton = uitogglebutton(app.ContrastfilteringButtonGroup);
            app.saturationadjustmentButton.Text = 'saturation adjustment';
            app.saturationadjustmentButton.Value = true;
            app.greyscalehistogramButton = uitogglebutton(app.ContrastfilteringButtonGroup);
            app.greyscalehistogramButton.Text = 'grey-scale histogram';
            app.adaptiveequalizationButton = uitogglebutton(app.ContrastfilteringButtonGroup);
            app.adaptiveequalizationButton.Text = 'adaptive equalization';
            app.ThresholdingmethodButtonGroup = uibuttongroup(app.ConfigurationPanel);
            app.ThresholdingmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingmethodButtonGroupSelectionChanged, true);
            app.ThresholdingmethodButtonGroup.Title = 'Thresholding method';
            app.OtsuButton = uitogglebutton(app.ThresholdingmethodButtonGroup);
            app.OtsuButton.Text = 'Otsu';
            app.OtsuButton.Value = true;
            app.KapurButton = uitogglebutton(app.ThresholdingmethodButtonGroup);
            app.KapurButton.Text = 'Kapur';
            app.FixedThresholdButton = uitogglebutton(app.ThresholdingmethodButtonGroup);
            app.FixedThresholdButton.Text = 'Fixed Threshold';
            app.Label = uilabel(app.ThresholdingmethodButtonGroup);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.Slider = uislider(app.ThresholdingmethodButtonGroup);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @ThresholdingmethodButtonGroupSelectionChanged, true);
            app.Slider.Visible = 'off';
            app.Label3 = uilabel(app.ThresholdingmethodButtonGroup);
            app.Label3.Visible = 'off';
            app.ImagePanel = uipanel(app.ConfigurationPanel);
            app.ImagePanel.Title = 'Image';
            app.UploadfileButton = uibutton(app.ImagePanel, 'push');
            app.UploadfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfileButtonPushed, true);
            app.UploadfileButton.Icon = fullfile(pathToMLAPP, 'AA PROCESAMIENTO', 'app resources', 'upload123.png');
            app.UploadfileButton.Text = 'Upload file';
            app.orLabel = uilabel(app.ImagePanel);
            app.orLabel.Text = 'or';
            app.ChoosesampleimageDropDownLabel = uilabel(app.ImagePanel);
            app.ChoosesampleimageDropDownLabel.Text = 'Choose sample image';
            app.ChoosesampleimageDropDown = uidropdown(app.ImagePanel);
            app.ChoosesampleimageDropDown.Items = {'1', '2', '3', 'none'};
            app.ChoosesampleimageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosesampleimageDropDownValueChanged, true);
            app.ChoosesampleimageDropDown.Placeholder = '...';
            app.ChoosesampleimageDropDown.Value = 'none';
            app.InstructionsButton = uibutton(app.ConfigurationPanel, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.Label7 = uilabel(app.ConfigurationPanel);
            app.Label7.Visible = 'off';
            app.Label7.Text = 'Label7';
            app.CreditsAknowledgementsButton = uibutton(app.ConfigurationPanel, 'push');
            app.CreditsAknowledgementsButton.ButtonPushedFcn = createCallbackFcn(app, @CreditsAknowledgementsButtonPushed, true);
            app.CreditsAknowledgementsButton.Text = 'Credits & Aknowledgements';
            app.ThumbnailPanel = uipanel(app.UIFigure);
            app.ThumbnailPanel.Title = 'Thumbnail';
            app.UIAxes = uiaxes(app.ThumbnailPanel);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.SegmentatedimagePanel = uipanel(app.UIFigure);
            app.SegmentatedimagePanel.Title = 'Segmentated image';
            app.UIAxes2 = uiaxes(app.SegmentatedimagePanel);
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.SegmentatedimagedetailsPanel = uipanel(app.UIFigure);
            app.SegmentatedimagedetailsPanel.Title = 'Segmentated image details';
            app.GridLayout = uigridlayout(app.SegmentatedimagedetailsPanel);
            app.ResolutionLabel = uilabel(app.GridLayout);
            app.ResolutionLabel.Text = 'Resolution:';
            app.ThresMethodLabel = uilabel(app.GridLayout);
            app.ThresMethodLabel.Text = 'Thres. Method:';
            app.ConFilteringLabel = uilabel(app.GridLayout);
            app.ConFilteringLabel.Text = 'Con. Filtering:';
            app.SpaFilteringLabel = uilabel(app.GridLayout);
            app.SpaFilteringLabel.Text = 'Spa. Filtering:';
            app.Label3_2 = uilabel(app.GridLayout);
            app.Label3_2.Visible = 'off';
            app.Label3_2.Text = 'Label3';
            app.Label4 = uilabel(app.GridLayout);
            app.Label4.Visible = 'off';
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.GridLayout);
            app.Label5.Visible = 'off';
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.GridLayout);
            app.Label6.Visible = 'off';
            app.Label6.Text = 'Label6';
            app.AnatoScopeLabel = uilabel(app.UIFigure);
            app.AnatoScopeLabel.Text = 'AnatoScope';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end